classdef Auroom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFrame               matlab.ui.Figure                   % UI Figure
        Label                   matlab.ui.control.Label            % Auroom
        Label2                  matlab.ui.control.Label            % Listen...
        MainTabGroup            matlab.ui.container.TabGroup       % Scene ...
        SceneSettingsTab        matlab.ui.container.Tab            % Scene ...
        SourcePanel             matlab.ui.container.Panel          % Source
        Label3                  matlab.ui.control.Label            % Position:
        LabelNumericEditField   matlab.ui.control.Label            % X:
        xSrcPosField            matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField2  matlab.ui.control.Label            % Y:
        ySrcPosField            matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField3  matlab.ui.control.Label            % Z:
        zSrcPosField            matlab.ui.control.NumericEditField % [-Inf ...
        srcApplyButton          matlab.ui.control.Button           % Apply
        Label9                  matlab.ui.control.Label            % meters
        Label10                 matlab.ui.control.Label            % meters
        Label11                 matlab.ui.control.Label            % meters
        ReceiverPanel           matlab.ui.container.Panel          % Receiver
        Label4                  matlab.ui.control.Label            % Position:
        Label5                  matlab.ui.control.Label            % X:
        xRecPosField            matlab.ui.control.NumericEditField % [-Inf ...
        Label6                  matlab.ui.control.Label            % Y:
        yRecPosField            matlab.ui.control.NumericEditField % [-Inf ...
        Label7                  matlab.ui.control.Label            % Z:
        zRecPosField            matlab.ui.control.NumericEditField % [-Inf ...
        recApplyButton          matlab.ui.control.Button           % Apply
        Label8                  matlab.ui.control.Label            % Orient...
        LabelNumericEditField4  matlab.ui.control.Label            % Phi:
        azimuthAngleField       matlab.ui.control.NumericEditField % [-Inf ...
        Label12                 matlab.ui.control.Label            % meters
        Label13                 matlab.ui.control.Label            % meters
        Label14                 matlab.ui.control.Label            % meters
        Label15                 matlab.ui.control.Label            % degrees
        Label16                 matlab.ui.control.Label            % Detect...
        LabelNumericEditField5  matlab.ui.control.Label            % R:
        detectorSizeField       matlab.ui.control.NumericEditField % [0 Inf]
        Label17                 matlab.ui.control.Label            % meters
        RoomPanel               matlab.ui.container.Panel          % Room
        LoadRoomFileButton      matlab.ui.control.Button           % Load new
        LabelListBox            matlab.ui.control.Label            % Wall m...
        MaterialsBox            matlab.ui.control.ListBox         
        AddMaterialButton       matlab.ui.control.Button           % Add
        SetMaterialButton       matlab.ui.control.Button           % Set
        Label26                 matlab.ui.control.Label            % Used r...
        MaterialNameField       matlab.ui.control.EditField       
        usedRoomFileField       matlab.ui.control.EditField       
        SimulationSettingsTab   matlab.ui.container.Tab            % Simula...
        LabelNumericEditField6  matlab.ui.control.Label            % Impuls...
        IrLengthField           matlab.ui.control.NumericEditField % [0 Inf]
        Label18                 matlab.ui.control.Label            % seconds
        LabelNumericEditField7  matlab.ui.control.Label            % Number...
        NumberOfParticlesField  matlab.ui.control.NumericEditField % [0 Inf]
        EnableReceiverTotalAttCheckBox matlab.ui.control.CheckBox % Enable ...
        EnableRecAtt            matlab.ui.control.Button           % Apply
        ApplyNumberOfParticles  matlab.ui.control.Button           % Apply
        ApplyIrLength           matlab.ui.control.Button           % Apply
        AuralizationSettingsTab matlab.ui.container.Tab            % Aurali...
        LabelEditField2         matlab.ui.control.Label            % Name o...
        SoundFileNameField      matlab.ui.control.EditField       
        Label21                 matlab.ui.control.Label            % samples
        LabelDiscreteKnob       matlab.ui.control.Label            % Aurali...
        ModeKnob                matlab.ui.control.DiscreteKnob     % Play, ...
        AuralizePanel           matlab.ui.container.Panel         
        Label19                 matlab.ui.control.Label            % Auralize!
        ToggleSwitch            matlab.ui.control.ToggleSwitch     % Off, On
        SimulationPanel         matlab.ui.container.Panel         
        Label25                 matlab.ui.control.Label            % Simula...
        StartSimulationButton   matlab.ui.control.Button           % Start
        LabelNumericEditField8  matlab.ui.control.Label            % Stream...
        FrameLengthField        matlab.ui.control.NumericEditField % [0 Inf]
    end


    properties (Access = private)
        simulationContext Simulation3dContext; % Description
        
        isSimulationReady = false;
    end

    methods (Access = private)
        function disableSimulationAndAuralization(app)
            app.StartSimulationButton.Enable = 'off';
            app.ToggleSwitch.Enable = 'off';
        end
        
        function enableSimulationAndAuralization(app)
            app.StartSimulationButton.Enable = 'on';
            app.ToggleSwitch.Enable = 'on';
        end
        
        function initializeSimulator(app)
            app.simulationContext = Simulation3dContext();
            app.enableSimulation();
        end

        function disableSimulation(app)
            app.StartSimulationButton.Enable = 'off';
        end

        function enableSimulation(app)
            app.StartSimulationButton.Enable = 'on';
        end

        function enableAuralization(app)
            app.ToggleSwitch.Enable = 'on';
        end
        
        function disableAuralization(app)
            app.ToggleSwitch.Enable = 'off';
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           initializeSimulator(app);
        end

        % srcApplyButton button pushed function
        function applySourceSettings(app)
           app.disableSimulationAndAuralization();
           x = calcSizeInPixels(app.xSrcPosField.Value);
           y = calcSizeInPixels(app.ySrcPosField.Value);
           z = calcSizeInPixels(app.zSrcPosField.Value);
           positionVector = Vec3d(x, y, z);
           app.simulationContext.setRecalcGeometryFlag(true);
           app.simulationContext.createSourceModel(positionVector);
           app.enableSimulationAndAuralization();
        end

        % recApplyButton button pushed function
        function applyReceiverSettings(app)
           app.disableSimulationAndAuralization();
           x = calcSizeInPixels(app.xRecPosField.Value);
           y = calcSizeInPixels(app.yRecPosField.Value);
           z = calcSizeInPixels(app.zRecPosField.Value);
           positionVector = Vec3d(x, y, z);
           app.simulationContext.setRecalcGeometryFlag(true);
           app.simulationContext.createReceiverModel(positionVector, app.azimuthAngleField.Value, app.detectorSizeField.Value);
           app.enableSimulationAndAuralization();
        end

        % LoadRoomFileButton button pushed function
        function loadRoomFile(app)
            app.disableSimulationAndAuralization();
            [filename, pathname] = uigetfile('Load room from file', '*.roo');
            try
                [vertices, faces] = parseRoomFile(filename);
            catch ME
                msgbox(ME.message, 'Error','error');
                app.enableSimulationAndAuralization();
                return;
            end
            try
                app.simulationContext.createRoomModel(vertices, faces);
            catch ME
                msgbox(ME.message, 'Error','error');
                app.enableSimulationAndAuralization();
                return;
            end
            app.simulationContext.setRecalcGeometryFlag(true);
            app.usedRoomFileField.Value = filename;
            app.enableSimulationAndAuralization();
        end

        % AddMaterialButton button pushed function
        function addMaterialButtonPushed(app)
            materialName = app.MaterialNameField.Value;
            filename = uigetfile('Choose impulse response file', '*.wav');
            if filename == 0
                return;
            end
            [ir, fs] = audioread(filename);
            material = Material(materialName, Filter(1, ir, fs));
            app.simulationContext.addMaterial(material);
            app.MaterialsBox.Items{end + 1} = material.getName();
        end

        % SetMaterialButton button pushed function
        function SetMaterialButtonPushed(app)
            app.disableSimulationAndAuralization();
            materialName = app.MaterialsBox.Value;
            app.simulationContext.setMaterial(materialName);
            app.simulationContext.setRecalcIrFlag(true);
            app.enableSimulationAndAuralization();
        end

        % ToggleSwitch value changed function
        function ToggleSwitchValueChanged(app)
            value = app.ToggleSwitch.Value;
            if ~app.isSimulationReady
                msgbox('You should first simulate the sound field.', 'Warning');
            end
            if strcmp(value, 'On')
                [left, right] = app.simulationContext.getFilters();
                app.disableSimulation();
                try
                    app.simulationContext.auralize(app.SoundFileNameField.Value, app.FrameLengthField.Value, left, right);
                catch ME
                    msgbox(ME.message, 'Error','error');
                    app.enableSimulationAndAuralization();
                    app.ToggleSwitch.Value = 'Off';
                    return;
                end
                app.ToggleSwitch.Value = 'Off';
            elseif strcmp(value, 'Off')
                app.simulationContext.stopAural();
                app.enableSimulation();
            end
            
        end

        % StartSimulationButton button pushed function
        function StartSimulationButtonPushed(app)
            try
                app.simulationContext.start();
            catch ME
                msgbox(ME.message, 'Warning');
                app.enableSimulationAndAuralization();
                return;
            end
            app.simulationContext.setRecalcGeometryFlag(false);
            app.simulationContext.setRecalcIrFlag(false);
            app.isSimulationReady = true;
            app.enableAuralization();
            app.disableSimulation();
        end

        % ApplyIrLength button pushed function
        function ApplyIrLengthButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.simulationContext.setIrLength(app.IrLengthField.Value);
            app.simulationContext.setRecalcGeometryFlag(true);
            app.enableSimulation();        
            app.enableSimulationAndAuralization();
        end

        % ApplyNumberOfParticles button pushed function
        function ApplyNumberOfParticlesButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.simulationContext.setParticlesNumber(app.NumberOfParticlesField.Value);
            app.simulationContext.setRecalcGeometryFlag(true);
            app.enableSimulation();
            app.enableSimulationAndAuralization();
        end

        % EnableRecAtt button pushed function
        function EnableRecAttButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.simulationContext.setAttenuationOnHead(app.EnableReceiverTotalAttCheckBox.Value);
            app.simulationContext.setRecalcIrFlag(true);
            app.enableSimulation();
            app.enableSimulationAndAuralization();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFrame = uifigure;
            app.MainFrame.Name = 'UI Figure';
            setAutoResize(app, app.MainFrame, false)
            app.Label = uilabel(app.MainFrame);
            app.Label.Text = 'Auroom';
            app.Label2 = uilabel(app.MainFrame);
            app.Label2.Text = 'Listen to your room.';
            app.MainTabGroup = uitabgroup(app.MainFrame);
            app.MainTabGroup.Units = 'pixels';
            app.SceneSettingsTab = uitab(app.MainTabGroup);
            app.SceneSettingsTab.Units = 'pixels';
            app.SceneSettingsTab.Title = 'Scene settings';
            app.SourcePanel = uipanel(app.SceneSettingsTab);
            app.SourcePanel.Title = 'Source';
            app.SourcePanel.Units = 'pixels';
            app.Label3 = uilabel(app.SourcePanel);
            app.Label3.Text = 'Position:';
            app.LabelNumericEditField = uilabel(app.SourcePanel);
            app.LabelNumericEditField.Text = 'X:';
            app.xSrcPosField = uieditfield(app.SourcePanel, 'numeric');
            app.xSrcPosField.Value = 2.5;
            app.LabelNumericEditField2 = uilabel(app.SourcePanel);
            app.LabelNumericEditField2.Text = 'Y:';
            app.ySrcPosField = uieditfield(app.SourcePanel, 'numeric');
            app.ySrcPosField.Value = 5;
            app.LabelNumericEditField3 = uilabel(app.SourcePanel);
            app.LabelNumericEditField3.Text = 'Z:';
            app.zSrcPosField = uieditfield(app.SourcePanel, 'numeric');
            app.zSrcPosField.Value = 1.5;
            app.srcApplyButton = uibutton(app.SourcePanel, 'push');
            app.srcApplyButton.ButtonPushedFcn = createCallbackFcn(app, @applySourceSettings);
            app.srcApplyButton.Text = 'Apply';
            app.Label9 = uilabel(app.SourcePanel);
            app.Label9.Text = 'meters';
            app.Label10 = uilabel(app.SourcePanel);
            app.Label10.Text = 'meters';
            app.Label11 = uilabel(app.SourcePanel);
            app.Label11.Text = 'meters';
            app.ReceiverPanel = uipanel(app.SceneSettingsTab);
            app.ReceiverPanel.Title = 'Receiver';
            app.ReceiverPanel.Units = 'pixels';
            app.Label4 = uilabel(app.ReceiverPanel);
            app.Label4.Text = 'Position:';
            app.Label5 = uilabel(app.ReceiverPanel);
            app.Label5.Text = 'X:';
            app.xRecPosField = uieditfield(app.ReceiverPanel, 'numeric');
            app.xRecPosField.Value = 2.5;
            app.Label6 = uilabel(app.ReceiverPanel);
            app.Label6.Text = 'Y:';
            app.yRecPosField = uieditfield(app.ReceiverPanel, 'numeric');
            app.yRecPosField.Value = 2.5;
            app.Label7 = uilabel(app.ReceiverPanel);
            app.Label7.Text = 'Z:';
            app.zRecPosField = uieditfield(app.ReceiverPanel, 'numeric');
            app.zRecPosField.Value = 1.5;
            app.recApplyButton = uibutton(app.ReceiverPanel, 'push');
            app.recApplyButton.ButtonPushedFcn = createCallbackFcn(app, @applyReceiverSettings);
            app.recApplyButton.Text = 'Apply';
            app.Label8 = uilabel(app.ReceiverPanel);
            app.Label8.Text = 'Orientation (azimuth angle):';
            app.LabelNumericEditField4 = uilabel(app.ReceiverPanel);
            app.LabelNumericEditField4.Text = 'Phi:';
            app.azimuthAngleField = uieditfield(app.ReceiverPanel, 'numeric');
            app.azimuthAngleField.Value = 180;
            app.Label12 = uilabel(app.ReceiverPanel);
            app.Label12.Text = 'meters';
            app.Label13 = uilabel(app.ReceiverPanel);
            app.Label13.Text = 'meters';
            app.Label14 = uilabel(app.ReceiverPanel);
            app.Label14.Text = 'meters';
            app.Label15 = uilabel(app.ReceiverPanel);
            app.Label15.Text = 'degrees';
            app.Label16 = uilabel(app.ReceiverPanel);
            app.Label16.Text = 'Detecting sphere''s radius:';
            app.LabelNumericEditField5 = uilabel(app.ReceiverPanel);
            app.LabelNumericEditField5.Text = 'R:';
            app.detectorSizeField = uieditfield(app.ReceiverPanel, 'numeric');
            app.detectorSizeField.LowerLimitInclusive = 'off';
            app.detectorSizeField.Limits = [0 Inf];
            app.detectorSizeField.Value = 0.7;
            app.Label17 = uilabel(app.ReceiverPanel);
            app.Label17.Text = 'meters';
            app.RoomPanel = uipanel(app.SceneSettingsTab);
            app.RoomPanel.Title = 'Room';
            app.RoomPanel.Units = 'pixels';
            app.LoadRoomFileButton = uibutton(app.RoomPanel, 'push');
            app.LoadRoomFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadRoomFile);
            app.LoadRoomFileButton.Text = 'Load new';
            app.LabelListBox = uilabel(app.RoomPanel);
            app.LabelListBox.Text = 'Wall materials list:';
            app.MaterialsBox = uilistbox(app.RoomPanel);
            app.MaterialsBox.Items = {};
            app.MaterialsBox.Value = {};
            app.AddMaterialButton = uibutton(app.RoomPanel, 'push');
            app.AddMaterialButton.ButtonPushedFcn = createCallbackFcn(app, @addMaterialButtonPushed);
            app.AddMaterialButton.Text = 'Add';
            app.SetMaterialButton = uibutton(app.RoomPanel, 'push');
            app.SetMaterialButton.ButtonPushedFcn = createCallbackFcn(app, @SetMaterialButtonPushed);
            app.SetMaterialButton.Text = 'Set';
            app.Label26 = uilabel(app.RoomPanel);
            app.Label26.Text = 'Used room file:';
            app.MaterialNameField = uieditfield(app.RoomPanel, 'text');
            app.usedRoomFileField = uieditfield(app.RoomPanel, 'text');
            app.usedRoomFileField.Editable = 'off';
            app.SimulationSettingsTab = uitab(app.MainTabGroup);
            app.SimulationSettingsTab.Units = 'pixels';
            app.SimulationSettingsTab.Title = 'Simulation settings';
            app.LabelNumericEditField6 = uilabel(app.SimulationSettingsTab);
            app.LabelNumericEditField6.Text = 'Impulse response''s length:';
            app.IrLengthField = uieditfield(app.SimulationSettingsTab, 'numeric');
            app.IrLengthField.LowerLimitInclusive = 'off';
            app.IrLengthField.Limits = [0 Inf];
            app.IrLengthField.Value = 1;
            app.Label18 = uilabel(app.SimulationSettingsTab);
            app.Label18.Text = 'seconds';
            app.LabelNumericEditField7 = uilabel(app.SimulationSettingsTab);
            app.LabelNumericEditField7.Text = 'Number of sound particles:';
            app.NumberOfParticlesField = uieditfield(app.SimulationSettingsTab, 'numeric');
            app.NumberOfParticlesField.LowerLimitInclusive = 'off';
            app.NumberOfParticlesField.Limits = [0 Inf];
            app.NumberOfParticlesField.Value = 1;
            app.EnableReceiverTotalAttCheckBox = uicheckbox(app.SimulationSettingsTab);
            app.EnableReceiverTotalAttCheckBox.Text = 'Enable total attenuation on receiver';
            app.EnableRecAtt = uibutton(app.SimulationSettingsTab, 'push');
            app.EnableRecAtt.ButtonPushedFcn = createCallbackFcn(app, @EnableRecAttButtonPushed);
            app.EnableRecAtt.Text = 'Apply';
            app.ApplyNumberOfParticles = uibutton(app.SimulationSettingsTab, 'push');
            app.ApplyNumberOfParticles.ButtonPushedFcn = createCallbackFcn(app, @ApplyNumberOfParticlesButtonPushed);
            app.ApplyNumberOfParticles.Text = 'Apply';
            app.ApplyIrLength = uibutton(app.SimulationSettingsTab, 'push');
            app.ApplyIrLength.ButtonPushedFcn = createCallbackFcn(app, @ApplyIrLengthButtonPushed);
            app.ApplyIrLength.Text = 'Apply';
            app.AuralizationSettingsTab = uitab(app.MainTabGroup);
            app.AuralizationSettingsTab.Units = 'pixels';
            app.AuralizationSettingsTab.Title = 'Auralization settings';
            app.LabelEditField2 = uilabel(app.AuralizationSettingsTab);
            app.LabelEditField2.Text = 'Name of the soundfile you want to auralize:';
            app.SoundFileNameField = uieditfield(app.AuralizationSettingsTab, 'text');
            app.Label21 = uilabel(app.AuralizationSettingsTab);
            app.Label21.Text = 'samples';
            app.LabelDiscreteKnob = uilabel(app.AuralizationSettingsTab);
            app.LabelDiscreteKnob.Text = 'Auralization mode';
            app.ModeKnob = uiknob(app.AuralizationSettingsTab, 'discrete');
            app.ModeKnob.Items = {'Play', 'Write to file'};
            app.ModeKnob.Value = 'Play';
            app.LabelNumericEditField8 = uilabel(app.AuralizationSettingsTab);
            app.LabelNumericEditField8.Text = 'Stream processing frame length:';
            app.FrameLengthField = uieditfield(app.AuralizationSettingsTab, 'numeric');
            app.FrameLengthField.Limits = [0 Inf];
            app.FrameLengthField.Value = 4096;
            app.AuralizePanel = uipanel(app.MainFrame);
            app.AuralizePanel.Units = 'pixels';
            app.Label19 = uilabel(app.AuralizePanel);
            app.Label19.Text = 'Auralize!';
            app.ToggleSwitch = uiswitch(app.AuralizePanel, 'toggle');
            app.ToggleSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged);
            app.ToggleSwitch.Enable = 'off';
            app.SimulationPanel = uipanel(app.MainFrame);
            app.SimulationPanel.Units = 'pixels';
            app.Label25 = uilabel(app.SimulationPanel);
            app.Label25.Text = 'Simulate sound field';
            app.StartSimulationButton = uibutton(app.SimulationPanel, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed);
            app.StartSimulationButton.Enable = 'off';
            app.StartSimulationButton.Text = 'Start';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Auroom()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFrame)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFrame)
        end
    end
end